classdef ClustAndHist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        HistogramsPanel             matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        numBins                     matlab.ui.control.NumericEditField
        NumberofbinsEditFieldLabel  matlab.ui.control.Label
        VariablestoClusterPanel     matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        VariableFrequency           matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        UIAxesH3                    matlab.ui.control.UIAxes
        UIAxesH2                    matlab.ui.control.UIAxes
        UIAxesH1                    matlab.ui.control.UIAxes
        Clustering                  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        UpdateButton                matlab.ui.control.Button
        ClusterButton               matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ClusterGrid                 matlab.ui.container.GridLayout
        ClustLabel                  matlab.ui.control.Label
        Clust                       matlab.ui.control.NumericEditField
        minPoints                   matlab.ui.control.NumericEditField
        epsilon                     matlab.ui.control.NumericEditField
        numLabel                    matlab.ui.control.Label
        epsLabel                    matlab.ui.control.Label
        Method                      matlab.ui.control.DropDown
        MethodLabel                 matlab.ui.control.Label
        Variables                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ZAxis                       matlab.ui.control.DropDown
        ZAxisLabel                  matlab.ui.control.Label
        YAxis                       matlab.ui.control.DropDown
        YAxisLabel                  matlab.ui.control.Label
        XAxis                       matlab.ui.control.DropDown
        XAxisLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        loc         % stores locations
        mainApp     % the main UI
        plotSct     % scatter plot handle
        params      % stores the statistical parameters
        curVar      % current variables for scatter plot
        indBr       % the brushed indices
        indOut      % indices of outliers
    end
    
    methods (Access = private)
        
          function indBrush = brushCallback(app,ohf,s)
            indBrush  = get(app.plotSct.pl1,'BrushData');
            app.indBr = indBrush'> 0;
            app.mainApp.results.slct = ...
                Dialogs.updateSelected(indBrush',app.loc.idx,...
                app.loc.batch,app.mainApp.det.Fit);
            update_plot(app.mainApp)
            Statistics.updateHists(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, spots, batch)
            app.loc   = spots; app.mainApp = mainapp;
            app.Clust.Value = round(sqrt(numel(spots))/2);
            app.indBr = []; % initialize with 
            app.params = Statistics.initializeParams(app.loc.stat);
            assignin('base','locs',app.loc)
            [app.plotSct.pl1,app.curVar] = Statistics.updateScatter(app);
            Statistics.updateHists(app)
            b1 = brush(app.plotSct.pl1);
            set(b1, 'ActionPostCallback', @(ohf, s) brushCallback(app,ohf,s), ...
            'enable', 'on');    b1.Color = 'cyan';
        end

        % Value changed function: XAxis, YAxis, ZAxis
        function XAxisValueChanged(app, event)
             [app.plotSct.pl1, app.curVar] = Statistics.updateScatter(app);  
             Statistics.updateHists(app)
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            app.indOut = Statistics.dbScanButton(app);
            app.mainApp.results = [];
            update_plot(app.mainApp);
            Statistics.updateHists(app)
            assignin('base','all',app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            Dialogs.removeOutliers(app.indOut,app.loc,app.mainApp.det.Fit,app)
            app.mainApp.state.db = 1;
            update_plot(app.mainApp)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.results = [];
            update_plot(app.mainApp)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Variables = uipanel(app.GridLayout);
            app.Variables.Title = 'Variables';
            app.GridLayout3 = uigridlayout(app.Variables);
            app.XAxisLabel = uilabel(app.GridLayout3);
            app.XAxisLabel.Text = 'X Axis:';
            app.XAxis = uidropdown(app.GridLayout3);
            app.XAxis.Items = {'Spot size (X)', 'Spot size (Y)', 'Average size', 'Average intensity', 'Maximum intensity', 'Eccentricity'};
            app.XAxis.ItemsData = [1 2 3 4 5 6 7];
            app.XAxis.ValueChangedFcn = createCallbackFcn(app, @XAxisValueChanged, true);
            app.XAxis.Value = 1;
            app.YAxisLabel = uilabel(app.GridLayout3);
            app.YAxisLabel.Text = 'Y Axis:';
            app.YAxis = uidropdown(app.GridLayout3);
            app.YAxis.Items = {'Spot size (X)', 'Spot size (Y)', 'Average size', 'Average intensity', 'Maximum intensity', 'Eccentricity'};
            app.YAxis.ItemsData = [1 2 3 4 5 6 7];
            app.YAxis.ValueChangedFcn = createCallbackFcn(app, @XAxisValueChanged, true);
            app.YAxis.Value = 2;
            app.ZAxisLabel = uilabel(app.GridLayout3);
            app.ZAxisLabel.Text = 'Z Axis:';
            app.ZAxis = uidropdown(app.GridLayout3);
            app.ZAxis.Items = {'None', 'Spot size (X)', 'Spot size (Y)', 'Average size', 'Average intensity', 'Maximum intensity', 'Eccentricity'};
            app.ZAxis.ItemsData = [0 1 2 3 4 5 6 7];
            app.ZAxis.ValueChangedFcn = createCallbackFcn(app, @XAxisValueChanged, true);
            app.ZAxis.Value = 0;
            app.Clustering = uipanel(app.GridLayout);
            app.Clustering.Title = 'Clustering';
            app.GridLayout4 = uigridlayout(app.Clustering);
            app.MethodLabel = uilabel(app.GridLayout4);
            app.MethodLabel.Text = 'Method:';
            app.Method = uidropdown(app.GridLayout4);
            app.Method.Items = {'k-means', 'DBScan'};
            app.Method.ItemsData = [1 2];
            app.Method.Value = 2;
            app.Panel = uipanel(app.GridLayout4);
            app.ClusterGrid = uigridlayout(app.Panel);
            app.epsLabel = uilabel(app.ClusterGrid);
            app.epsLabel.Text = 'Point neighborhood';
            app.numLabel = uilabel(app.ClusterGrid);
            app.numLabel.Visible = 'off';
            app.numLabel.Text = 'Min. number of points';
            app.epsilon = uieditfield(app.ClusterGrid, 'numeric');
            app.epsilon.Value = 0.05;
            app.minPoints = uieditfield(app.ClusterGrid, 'numeric');
            app.minPoints.Value = 20;
            app.Clust = uieditfield(app.ClusterGrid, 'numeric');
            app.Clust.RoundFractionalValues = 'on';
            app.Clust.Visible = 'off';
            app.ClustLabel = uilabel(app.ClusterGrid);
            app.ClustLabel.Text = 'Number of Clusters';
            app.ClusterButton = uibutton(app.GridLayout4, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            app.UpdateButton = uibutton(app.GridLayout4, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.VariableFrequency = uipanel(app.GridLayout);
            app.VariableFrequency.Title = 'Variable Frequency Distribution';
            app.GridLayout6 = uigridlayout(app.VariableFrequency);
            app.UIAxesH1 = uiaxes(app.GridLayout6);
            zlabel(app.UIAxesH1, 'Z')
            app.UIAxesH2 = uiaxes(app.GridLayout6);
            zlabel(app.UIAxesH2, 'Z')
            app.UIAxesH3 = uiaxes(app.GridLayout6);
            zlabel(app.UIAxesH3, 'Z')
            app.VariablestoClusterPanel = uipanel(app.GridLayout);
            app.VariablestoClusterPanel.Title = 'Variables to Cluster';
            app.GridLayout5 = uigridlayout(app.VariablestoClusterPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistogramsPanel = uipanel(app.GridLayout);
            app.HistogramsPanel.Title = 'Histograms';
            app.GridLayout7 = uigridlayout(app.HistogramsPanel);
            app.NumberofbinsEditFieldLabel = uilabel(app.GridLayout7);
            app.NumberofbinsEditFieldLabel.Text = 'Number of bins:';
            app.numBins = uieditfield(app.GridLayout7, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClustAndHist(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end