classdef FourierEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        DropDown                 matlab.ui.control.DropDown
        Panel                    matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        Inverttheimage           matlab.ui.control.CheckBox
        Domain                   matlab.ui.control.Switch
        ImageenhancementPanel    matlab.ui.container.Panel
        GridLayout2_2            matlab.ui.container.GridLayout
        Percentage               matlab.ui.control.Spinner
        PercentageLabel          matlab.ui.control.Label
        EnhanceButton            matlab.ui.control.Button
        PeakDetectionPanel       matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        DetectButton             matlab.ui.control.Button
        Threshold                matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        AxesEnhanced             matlab.ui.control.UIAxes
        AxesOriginal             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % The main application
    end
    
    properties (Access = public)
       data   % Stores variables passed between functions
       peaks  % detected peaks
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, image)
            app.MainApp    = mainapp;
            app.data       = image;
            app.data.imF   = Fourier.Update(app.data.imRaw,app.data.imComp,...
                app.AxesOriginal,app.Domain.Value);
            linkaxes([app.AxesOriginal,app.AxesEnhanced])
        end

        % Value changed function: Domain
        function DomainValueChanged(app, event)
            Fourier.Update(app.data.imRaw,app.data.imComp,...
                app.AxesOriginal,app.Domain.Value,app.data.imF,app.peaks);
            try Fourier.Update...
                (app.data.imEnhanced,[],app.AxesEnhanced,app.Domain.Value); catch
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.Domain.Value = 1;
            [app.peaks,app.Threshold.Value,app.MainApp.fourier.Grid] = ...
                Fourier.FindPeaks(app.data,app.Threshold.Value,app.Inverttheimage.Value);
            Fourier.Update(app.data.imRaw,app.data.imComp,app.AxesOriginal,...
                app.Domain.Value,app.data.imF,app.peaks);
            assignin('base','grid',app.MainApp.fourier.Grid);
            assignin('base','peaks',app.peaks)
        end

        % Button pushed function: EnhanceButton
        function EnhanceButtonPushed(app, event)
            app.Domain.Value = 0;
            inv = app.Inverttheimage.Value;
            app.data.imEnhanced = ...
                Fourier.Enhance(app.data,app.peaks,app.Percentage.Value,inv);
            Fourier.Update(app.data.imEnhanced,[],app.AxesEnhanced,...
                app.Domain.Value);
            Fourier.Update(app.data.imRaw,app.data.imComp,app.AxesOriginal,...
                app.Domain.Value);
            app.MainApp.images.imEnhanced = app.data.imEnhanced;
            app.MainApp.UseTheEnhancedImage.Enable = 1;
            assignin('base','data',app.data)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxesOriginal = uiaxes(app.GridLayout);
            zlabel(app.AxesOriginal, 'Z')
            app.AxesOriginal.XTick = [];
            app.AxesOriginal.YTick = [];
            app.AxesEnhanced = uiaxes(app.GridLayout);
            zlabel(app.AxesEnhanced, 'Z')
            app.AxesEnhanced.XTick = [];
            app.AxesEnhanced.YTick = [];
            app.PeakDetectionPanel = uipanel(app.GridLayout);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.GridLayout2 = uigridlayout(app.PeakDetectionPanel);
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.ThresholdEditFieldLabel.Text = 'Threshold:';
            app.Threshold = uieditfield(app.GridLayout2, 'numeric');
            app.DetectButton = uibutton(app.GridLayout2, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ImageenhancementPanel = uipanel(app.GridLayout);
            app.ImageenhancementPanel.Title = 'Image enhancement';
            app.GridLayout2_2 = uigridlayout(app.ImageenhancementPanel);
            app.EnhanceButton = uibutton(app.GridLayout2_2, 'push');
            app.EnhanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.EnhanceButton.Text = 'Enhance';
            app.PercentageLabel = uilabel(app.GridLayout2_2);
            app.PercentageLabel.Text = 'Percentage:';
            app.Percentage = uispinner(app.GridLayout2_2);
            app.Percentage.Step = 0.1;
            app.Percentage.Limits = [0 1000];
            app.Percentage.Value = 1;
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Domain = uiswitch(app.GridLayout3, 'slider');
            app.Domain.Items = {'Spatial Domain', 'Frequency Domain'};
            app.Domain.ItemsData = [0 1];
            app.Domain.ValueChangedFcn = createCallbackFcn(app, @DomainValueChanged, true);
            app.Domain.Value = 0;
            app.Inverttheimage = uicheckbox(app.GridLayout3);
            app.Inverttheimage.Text = 'Invert the image';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Original image', 'Periodic Pattern'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Original image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierEnhancement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end