classdef ParamsAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AnalysisParametersPanel  matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        GridLayout7              matlab.ui.container.GridLayout
        Save                     matlab.ui.control.Button
        NoFitting                matlab.ui.control.CheckBox
        FitWindow                matlab.ui.control.NumericEditField
        WindowSizeLabel_2        matlab.ui.control.Label
        Fit                      matlab.ui.control.DropDown
        FittingLabel             matlab.ui.control.Label
        GradientThreshold        matlab.ui.control.NumericEditField
        GradientThresholdEditFieldLabel  matlab.ui.control.Label
        GradientWindow           matlab.ui.control.NumericEditField
        WindowSizeLabel          matlab.ui.control.Label
        LocalizationLabel        matlab.ui.control.Label
        FilteringLabel           matlab.ui.control.Label
        BorderSize               matlab.ui.control.NumericEditField
        BordersizeLabel          matlab.ui.control.Label
        Denoising                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp % the main application
    end
    
    properties (Access = public)
        params % Saved parameter matrix
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, st)
            app.MainApp = mainapp;
            app.params = Initialize.getCurrentParams(app);
            switch st
                case 0
                    app.UIFigure.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            paramsNew = Initialize.getCurrentParams(app);
            app.MainApp.GradientThreshold.Value = paramsNew.gradThresh;
            app.MainApp.GradientWindow.Value    = paramsNew.gradWindow;
            app.MainApp.params = paramsNew;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AnalysisParametersPanel = uipanel(app.UIFigure);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.GridLayout8 = uigridlayout(app.AnalysisParametersPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout8);
            app.Denoising = uidropdown(app.GridLayout7);
            app.Denoising.Items = {'None', 'A-trous-wavelet', 'Fourier denoise'};
            app.Denoising.ItemsData = [0 1 2];
            app.Denoising.Value = 0;
            app.BordersizeLabel = uilabel(app.GridLayout7);
            app.BordersizeLabel.Text = {'Border '; 'Size'};
            app.BorderSize = uieditfield(app.GridLayout7, 'numeric');
            app.BorderSize.Value = 5;
            app.FilteringLabel = uilabel(app.GridLayout7);
            app.FilteringLabel.Text = 'Filtering';
            app.LocalizationLabel = uilabel(app.GridLayout7);
            app.LocalizationLabel.Text = 'Localization';
            app.WindowSizeLabel = uilabel(app.GridLayout7);
            app.WindowSizeLabel.Text = {'Window'; 'Size'};
            app.GradientWindow = uieditfield(app.GridLayout7, 'numeric');
            app.GradientWindow.Value = 3;
            app.GradientThresholdEditFieldLabel = uilabel(app.GridLayout7);
            app.GradientThresholdEditFieldLabel.Text = 'Gradient Threshold';
            app.GradientThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.GradientThreshold.Value = 100;
            app.FittingLabel = uilabel(app.GridLayout7);
            app.FittingLabel.Text = 'Fitting';
            app.Fit = uidropdown(app.GridLayout7);
            app.Fit.Items = {'Gaussian MLE', 'Weighted Centroid'};
            app.Fit.ItemsData = [0 1];
            app.Fit.Value = 0;
            app.WindowSizeLabel_2 = uilabel(app.GridLayout7);
            app.WindowSizeLabel_2.Text = 'Window Size';
            app.FitWindow = uieditfield(app.GridLayout7, 'numeric');
            app.FitWindow.Value = 3;
            app.NoFitting = uicheckbox(app.GridLayout7);
            app.NoFitting.Text = 'No Gaussian fitting';
            app.Save = uibutton(app.GridLayout7, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamsAnalysisApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end