classdef createROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel_5                      matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        ExporttheROIButton           matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        DrawNewButton                matlab.ui.control.Button
        Panel_3                      matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        CalculateButton              matlab.ui.control.Button
        Areathreshold                matlab.ui.control.NumericEditField
        AreathresholdEditFieldLabel  matlab.ui.control.Label
        Intensitythreshold           matlab.ui.control.NumericEditField
        IntensitythresholdEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
          MainApp % the main application
          imageIn % the input image
          hf      % figure handle
    end
    
    properties (Access = public)
        imROI   % app output defined ROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, image)
            app.MainApp = mainapp;
            app.imageIn = image;

            colormap(app.UIAxes,'gray')
            imagesc(app.UIAxes,app.imageIn)
            axis(app.UIAxes,[1 size(image,1) 1 size(image,2)])
            hold(app.UIAxes,'on')
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [~,app.hf] = calculate_roi(app.imageIn,app.UIAxes,...
                app.Areathreshold.Value, app.Intensitythreshold.Value);
        end

        % Button pushed function: DrawNewButton
        function DrawNewButtonPushed(app, event)
            hold(app.UIAxes,'on');
            hn = drawfreehand(app.UIAxes);
            app.hf{end+1} = hn;
        end

        % Button pushed function: ExporttheROIButton
        function ExporttheROIButtonPushed(app, event)
            app.imROI = ROI.posToBW(app.imageIn,app.hf);
            app.MainApp.ImporttheROI.Enable = 1;
            app.MainApp.images.imROI = app.imROI;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.IntensitythresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.IntensitythresholdEditFieldLabel.Text = 'Intensity threshold';
            app.Intensitythreshold = uieditfield(app.GridLayout2, 'numeric');
            app.Intensitythreshold.Value = 2.5;
            app.AreathresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.AreathresholdEditFieldLabel.Text = 'Area threshold';
            app.Areathreshold = uieditfield(app.GridLayout2, 'numeric');
            app.Areathreshold.Value = 6;
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel_4);
            app.DrawNewButton = uibutton(app.GridLayout2_2, 'push');
            app.DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNewButtonPushed, true);
            app.DrawNewButton.Text = ' Draw New';
            app.ResetButton = uibutton(app.GridLayout2_2, 'push');
            app.ResetButton.Text = 'Reset';
            app.ExporttheROIButton = uibutton(app.GridLayout2_2, 'push');
            app.ExporttheROIButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttheROIButtonPushed, true);
            app.ExporttheROIButton.Text = 'Export the ROI';
            app.Panel_5 = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel_5);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createROI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end