classdef nanoLoci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        ImportFluorescenceImageMenu     matlab.ui.container.Menu
        ImportBrightfieldImageMenu      matlab.ui.container.Menu
        ImportImageBatchMenu            matlab.ui.container.Menu
        ImportBinaryROIMenu             matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        DetectionsMenu                  matlab.ui.container.Menu
        toafileMenu                     matlab.ui.container.Menu
        toworkspaceMenu                 matlab.ui.container.Menu
        ROIMenu                         matlab.ui.container.Menu
        FluorescenceMenu                matlab.ui.container.Menu
        BrightfieldMenu                 matlab.ui.container.Menu
        ProcessMenu_2                   matlab.ui.container.Menu
        OutlierExclusionMenu_2          matlab.ui.container.Menu
        DBScanMenu_2                    matlab.ui.container.Menu
        VoronoiMenu_2                   matlab.ui.container.Menu
        StatisticsEvaluationMenu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ImageAnalysisTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ContrastPanel                   matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        SliderContrast                  matlab.ui.control.Slider
        ImagePanel                      matlab.ui.container.Panel
        GridLayout7_2                   matlab.ui.container.GridLayout
        ImporttheROI                    matlab.ui.control.StateButton
        UseTimeAveragedImage            matlab.ui.control.CheckBox
        UseTheEnhancedImage             matlab.ui.control.CheckBox
        AnalysisPanel                   matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        LocalizeandFitButton            matlab.ui.control.Button
        GradientThreshold               matlab.ui.control.NumericEditField
        GradientThresholdEditFieldLabel  matlab.ui.control.Label
        GradientWindow                  matlab.ui.control.NumericEditField
        WindowSizeLabel                 matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        AverageROIintensityLabel        matlab.ui.control.Label
        TotalROIintensity               matlab.ui.control.NumericEditField
        BackgroundIntensity             matlab.ui.control.NumericEditField
        BackgroundintensityLabel        matlab.ui.control.Label
        SumIntensityField               matlab.ui.control.NumericEditField
        AvgIntensityField               matlab.ui.control.NumericEditField
        NbNwField                       matlab.ui.control.NumericEditField
        TotalsignalintensityLabel       matlab.ui.control.Label
        AveragesignalintensityLabel     matlab.ui.control.Label
        NumberofdetectionsLabel         matlab.ui.control.Label
        FlAxes1                         matlab.ui.control.UIAxes
        MultidomainImageVisualizationTab  matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ObtaintheROIButton              matlab.ui.control.Button
        FourierDomainEnhancementButton  matlab.ui.control.Button
        FlAxes                          matlab.ui.control.UIAxes
        BrAxes                          matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        Domain                          matlab.ui.control.CheckBox
        ShowTheFiltered                 matlab.ui.control.CheckBox
        ShowTheDetections               matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        FrameLabel                      matlab.ui.control.Label
        BatchLabel                      matlab.ui.control.Label
        SliderFr                        matlab.ui.control.Slider
        SliderBatch                     matlab.ui.control.Slider
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeParametersMenu            matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        ImagingMenu                     matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
       
       FourierApp        % pop-up interface to use the selected image for enhancement
       ROIApp            % pop-up interface to calculate and draw a ROI on the brightfield
       ParamsApp         % pop-up interface to change analysis parameters
       VoronoiApp
       DbScan
       spotStat
    end

    properties (Access = public)
        images     % stores the images
        det        % stores the detections
        fourier    % stores fourier transforms, etc
        params     % stores method parameters
        results    % calculated results
        temp       % temporaty variables 
        state      % image visualization state
        paths      % stores directories, etc
        intensities% 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.ParamsApp = ParamsAnalysisApp(app,0);
           app.params = app.ParamsApp.params;
           delete(app.ParamsApp)
           linkaxes([app.FlAxes, app.BrAxes])
        end

        % Menu selected function: ImportFluorescenceImageMenu
        function ImportFluorescenceImageMenuSelected(app, event)
            Initialize.resetAll(app);
            app.paths.filenameFl = Image.ReadPath('one');
            try
            [app.images.imAll, app.images.imAv] = ...
                Image.Import(app.paths.filenameFl);
            catch ME
            assignin('base',"error1",ME)
            tf = strcmp(ME.identifier,'MATLAB:unexpectedCPPexception');
            if tf
                errordlg("E001: Error loading the image");
            end
            end
            app.state = Initialize.getCurrentState(app);
            [app.images.imCurrent,~] = ...
                Initialize.getCurrentImage(app.state,app.images);
            SliderControl.updateLimits(size(app.images.imAll,4),app.SliderBatch);
            SliderControl.updateLimits(size(app.images.imAll,3),app.SliderFr);
            Image.Update(app.paths.filenameFl, 1, app.FlAxes1, app.images.imAll);
            Image.Update(app.paths.filenameFl, 1, app.FlAxes, app.images.imAll);
        end

        % Menu selected function: ImportBrightfieldImageMenu
        function ImportBrightfieldImageMenuSelected(app, event)
            app.paths.filenameBr = Image.ReadPath('one');
            [app.images.BrAll,app.images.imBrAv]   = Image.Import(app.paths.filenameBr);
            [~,app.images.BrCurrent] = ...
                Initialize.getCurrentImage(app.state,app.images);
            Image.Update(app.paths.filenameBr, 1, app.BrAxes, app.images.BrAll);
            app.Domain.Value = 0;
        end

        % Menu selected function: ImportImageBatchMenu
        function ImportImageBatchMenuSelected(app, event)
            Initialize.resetAll(app);
            app.ImporttheROI.Enable =0;
            app.UseTheEnhancedImage.Enable = 0;
            
            app.paths.filenameFl = Image.ReadPath('multi');
            [app.images.imAll, app.images.imAv] = ...
                Image.Import(app.paths.filenameFl);
            app.params = Initialize.getCurrentParams(app,app.params);
            app.state = Initialize.getCurrentState(app);
            [app.images.imCurrent,~] = ...
                Initialize.getCurrentImage(app.state,app.images);
            SliderControl.updateLimits(size(app.images.imAll,4),app.SliderBatch);
            SliderControl.updateLimits(size(app.images.imAll,3),app.SliderFr);
            Image.Update(app.paths.filenameFl, 1, app.FlAxes1, app.images.imAll);
            Image.Update(app.paths.filenameFl, 1, app.FlAxes, app.images.imAll);
        end

        % Value changed function: SliderBatch
        function SliderBatchValueChanged(app, event)
            SliderControl.tickValues(event.Source);
            [app.images.imCurrent, app.images.BrCurrent] =...
                Initialize.getCurrentImage(app.state, app.images);
            Image.Update(app.paths.filenameFl, app.SliderBatch.Value, app.FlAxes1);
            update_plot(app);
        end

        % Value changed function: SliderFr
        function SliderFrValueChanged(app, event)
            SliderControl.tickValues(event.Source);
            [app.images.imCurrent, app.images.BrCurrent] =...
                Initialize.getCurrentImage(app.state, app.images);
            update_plot(app);
        end

        % Value changed function: ShowTheDetections
        function ShowTheDetectionsValueChanged(app, event)
            update_plot(app);
        end

        % Value changed function: ShowTheFiltered
        function ShowTheFilteredValueChanged(app, event)
            try
                update_plot(app);
            catch ME
                assignin('base','Me',ME)
            end

        end

        % Value changed function: UseTimeAveragedImage
        function UseTimeAveragedImageValueChanged(app, event)
            app.state = Initialize.getCurrentState(app);
            [app.images.imCurrent, app.images.BrCurrent]...
                = Initialize.getCurrentImage(app.state, app.images);
            app.images.imFilt    = Analysis.filterTheImage(app.params,app.images.imCurrent);
            [~,app.det] = Initialize.resetParams([],app.det,app.UseTimeAveragedImage.Value);
            app.results.slc = [];
            update_plot(app);
        end

        % Button down function: MultidomainImageVisualizationTab
        function MultidomainImageVisualizationTabButtonDown(app, event)
            SliderControl.pageUpdate(app,0)
        end

        % Button down function: ImageAnalysisTab
        function ImageAnalysisTabButtonDown(app, event)
            SliderControl.pageUpdate(app,1)
        end

        % Value changed function: Domain
        function DomainValueChanged(app, event)
            update_plot(app);   
        end

        % Button pushed function: FourierDomainEnhancementButton
        function FourierDomainEnhancementButtonPushed(app, event)
            [imDomain1, imDomain2] = ...
                Dialogs.ChooseDomain(app.images);
            app.temp.imRaw  =...
                Initialize.getCurrentFrame(imDomain1,app.SliderFr,app.SliderBatch);
            app.temp.imComp =...
                Initialize.getCurrentFrame(imDomain2,app.SliderFr,app.SliderBatch);
            app.FourierApp =  FourierEnhancement(app,app.temp);
        end

        % Value changed function: UseTheEnhancedImage
        function UseTheEnhancedImageValueChanged(app, event)
            app.state = Initialize.getCurrentState(app);
            [app.images.imCurrent, ~]...
                = Initialize.getCurrentImage(app.state, app.images,0);
            update_plot(app)
        end

        % Button pushed function: ObtaintheROIButton
        function ObtaintheROIButtonPushed(app, event)
            app.ROIApp = createROI(app,app.images.imBrAv);
%             try
%                 app.ROIApp = createROI(app,app.images.imBrAv);
%             catch
%                 uialert(app.UIFigure,'Please upload the brightfield image','Error')
%             end
        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
            app.ParamsApp = ParamsAnalysisApp(app,1);
        end

        % Button pushed function: LocalizeandFitButton
        function LocalizeandFitButtonPushed(app, event)
            app.params = Initialize.getCurrentParams(app,app.params);
            app.state  = Initialize.getCurrentState(app);
            app.images.imCurrent = ...
                Initialize.getCurrentImage(app.state,app.images,0);
            app.images.imFilt = ...
                Analysis.filterTheImage(app.params,app.images.imCurrent);
            app.images.imRawCurr = Initialize.getCurrentImage(app.state,app.images,1);
            [app.det.Fit, app.det.Loc, app.det.LocFinal] = ...
                Analysis.LocAndFit(app.params,app.state,app.images); 
            app.results = [];
            try
                app.intensities = Analysis.findIntensity(app.det.Fit,app.images,app.state,app.params);
            catch ME
               uialert(app.UIFigure,'No detections in the image.','Error')
            end
            try
                update_plot(app);
            catch ME
                assignin('base','please',ME)
            end
        end

        % Value changed function: ImporttheROI
        function ImporttheROIValueChanged(app, event)
            app.state = Initialize.getCurrentState(app);
            app.images.imCurrent = ...
                Initialize.getCurrentImage(app.state,app.images);
            update_plot(app);
        end

        % Menu selected function: VoronoiMenu_2
        function VoronoiMenuSelected(app, event)
                app.state = Initialize.getCurrentState(app);
            [app.images.imCurrent, ~]...
                = Initialize.getCurrentImage(app.state, app.images,0);
           % try
                disp(app.fourier.Grid);
                if app.SliderBatch.Enable ==0
                    batch = 1;
                end
                if app.SliderFr.Enable ==0
                    fr = 1;
                end
                app.VoronoiApp = voronoiApp(app, app.det.Fit{fr,batch},...
                app.fourier.Grid,app.images.imCurrent,fr,batch);
            %catch ME
                %uialert(app.UIFigure,'Please perform Fourier enhancement and spot detection.','Error')
                %assignin('base','sm',ME);
                assignin('base','fg',app.fourier)
            %end
            assignin('base','fg',app.fourier)
            app.state.vor = 1;
        end

        % Menu selected function: DBScanMenu_2
        function DBScanMenu_2Selected(app, event)
             [spots, indx] = Dialogs.DbScan(app);
             app.results.detAll = spots;
             app.DbScan = dbStat(app,spots,app.SliderBatch.Value);
        end

        % Value changed function: SliderContrast
        function SliderContrastValueChanged(app, event)
       update_plot(app)
        end

        % Menu selected function: toworkspaceMenu
        function toworkspaceMenuSelected(app, event)
            Export.toWorkspace(app)
        end

        % Menu selected function: toafileMenu
        function toafileMenuSelected(app, event)
            try
            win = (app.GradientWindow.Value-1)*0.5;
            Export.toFile(app.images.imAll,app.det.Fit,app.paths.filenameFl,...
               win,app.UseTimeAveragedImage.Value);
            catch ME
                if strcmp(ME.identifier,'MATLAB:structRefFromNonStruct')
                    uialert(app.UIFigure,'Please perform spot detection.','Error')
                end
                assignin('base','errorSave',ME)
            end
        end

        % Menu selected function: FluorescenceMenu
        function FluorescenceMenuSelected(app, event)

            win = 0.5*(app.GradientWindow.Value - 1);
            [currentIm,frame,batch] = Initialize.getCurrentFrame(app.images.imAll,...
                app.SliderFr,app.SliderBatch);
            try
                Export.toROIfl(currentIm,app.det.Fit{frame,batch},win,...
                    app.paths.filenameFl{batch},frame)
            catch ME
                assignin('base','app',app)
                assignin('base','errr',ME)
            end
        end

        % Menu selected function: ImportBinaryROIMenu
        function ImportBinaryROIMenuSelected(app, event)
            filenameROI       = Image.ReadPath('one');
            imROI  = Image.Import(filenameROI);
            app.images.imROI = imROI>1;
            app.ImporttheROI.Enable = 1;
        end

        % Callback function
        function TestMenuSelected(app, event)
            assignin('base','main',app)
            assignin('base','db',app.DbScan)
            assignin('base','stats',app.spotStat)
        end

        % Menu selected function: StatisticsEvaluationMenu
        function StatisticsEvaluationMenuSelected(app, event)
            [spots, ~] = Dialogs.spotStatistics(app);
            app.results.detAll = spots;
            %app.spotStat= spotStatisticsApp(app,spots);
            app.spotStat= ClustAndHist(app,spots,app.SliderBatch.Value);
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.ImportFluorescenceImageMenu = uimenu(app.ImportMenu);
            app.ImportFluorescenceImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportFluorescenceImageMenuSelected, true);
            app.ImportFluorescenceImageMenu.Text = 'Import Fluorescence Image';
            app.ImportBrightfieldImageMenu = uimenu(app.ImportMenu);
            app.ImportBrightfieldImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportBrightfieldImageMenuSelected, true);
            app.ImportBrightfieldImageMenu.Text = 'Import Brightfield Image';
            app.ImportImageBatchMenu = uimenu(app.ImportMenu);
            app.ImportImageBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageBatchMenuSelected, true);
            app.ImportImageBatchMenu.Text = 'Import Image Batch';
            app.ImportBinaryROIMenu = uimenu(app.ImportMenu);
            app.ImportBinaryROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportBinaryROIMenuSelected, true);
            app.ImportBinaryROIMenu.Text = 'Import Binary ROI';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.DetectionsMenu = uimenu(app.ExportMenu);
            app.DetectionsMenu.Text = 'Detections';
            app.toafileMenu = uimenu(app.DetectionsMenu);
            app.toafileMenu.MenuSelectedFcn = createCallbackFcn(app, @toafileMenuSelected, true);
            app.toafileMenu.Text = 'to a file';
            app.toworkspaceMenu = uimenu(app.DetectionsMenu);
            app.toworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @toworkspaceMenuSelected, true);
            app.toworkspaceMenu.Text = 'to workspace';
            app.ROIMenu = uimenu(app.ExportMenu);
            app.ROIMenu.Text = 'ROI';
            app.FluorescenceMenu = uimenu(app.ROIMenu);
            app.FluorescenceMenu.MenuSelectedFcn = createCallbackFcn(app, @FluorescenceMenuSelected, true);
            app.FluorescenceMenu.Text = 'Fluorescence';
            app.BrightfieldMenu = uimenu(app.ROIMenu);
            app.BrightfieldMenu.Text = 'Brightfield';
            app.ProcessMenu_2 = uimenu(app.UIFigure);
            app.ProcessMenu_2.Text = 'Process';
            app.OutlierExclusionMenu_2 = uimenu(app.ProcessMenu_2);
            app.OutlierExclusionMenu_2.Text = 'Outlier Exclusion ';
            app.DBScanMenu_2 = uimenu(app.OutlierExclusionMenu_2);
            app.DBScanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DBScanMenu_2Selected, true);
            app.DBScanMenu_2.Text = 'DB Scan';
            app.VoronoiMenu_2 = uimenu(app.OutlierExclusionMenu_2);
            app.VoronoiMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VoronoiMenuSelected, true);
            app.VoronoiMenu_2.Text = 'Voronoi';
            app.StatisticsEvaluationMenu = uimenu(app.ProcessMenu_2);
            app.StatisticsEvaluationMenu.MenuSelectedFcn = createCallbackFcn(app, @StatisticsEvaluationMenuSelected, true);
            app.StatisticsEvaluationMenu.Text = 'Statistics Evaluation';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SliderBatch = uislider(app.GridLayout3);
            app.SliderBatch.Limits = [1 2];
            app.SliderBatch.MajorTicks = [];
            app.SliderBatch.ValueChangedFcn = createCallbackFcn(app, @SliderBatchValueChanged, true);
            app.SliderBatch.MinorTicks = [];
            app.SliderBatch.Value = 1;
            app.SliderFr = uislider(app.GridLayout3);
            app.SliderFr.Limits = [1 2];
            app.SliderFr.MajorTicks = [];
            app.SliderFr.ValueChangedFcn = createCallbackFcn(app, @SliderFrValueChanged, true);
            app.SliderFr.MinorTicks = [];
            app.SliderFr.Value = 1;
            app.BatchLabel = uilabel(app.GridLayout3);
            app.BatchLabel.Text = 'Batch';
            app.FrameLabel = uilabel(app.GridLayout3);
            app.FrameLabel.Text = 'Frame';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout14 = uigridlayout(app.Panel_5);
            app.ShowTheDetections = uicheckbox(app.GridLayout14);
            app.ShowTheDetections.ValueChangedFcn = createCallbackFcn(app, @ShowTheDetectionsValueChanged, true);
            app.ShowTheDetections.Text = '   Show the detections';
            app.ShowTheDetections.Value = true;
            app.ShowTheFiltered = uicheckbox(app.GridLayout14);
            app.ShowTheFiltered.ValueChangedFcn = createCallbackFcn(app, @ShowTheFilteredValueChanged, true);
            app.ShowTheFiltered.Text = '   Show the filtered image';
            app.Domain = uicheckbox(app.GridLayout14);
            app.Domain.ValueChangedFcn = createCallbackFcn(app, @DomainValueChanged, true);
            app.Domain.Visible = 'off';
            app.Domain.Text = '   Show the Fast Fourier Transform';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.ImageAnalysisTab.ButtonDownFcn = createCallbackFcn(app, @ImageAnalysisTabButtonDown, true);
            app.GridLayout5 = uigridlayout(app.ImageAnalysisTab);
            app.FlAxes1 = uiaxes(app.GridLayout5);
            title(app.FlAxes1, 'Title')
            app.FlAxes1.XTick = [];
            app.FlAxes1.YTick = [];
            app.ResultsPanel = uipanel(app.GridLayout5);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout12 = uigridlayout(app.ResultsPanel);
            app.NumberofdetectionsLabel = uilabel(app.GridLayout12);
            app.NumberofdetectionsLabel.Text = 'Number of detections';
            app.AveragesignalintensityLabel = uilabel(app.GridLayout12);
            app.AveragesignalintensityLabel.Text = 'Average signal intensity';
            app.TotalsignalintensityLabel = uilabel(app.GridLayout12);
            app.TotalsignalintensityLabel.Text = 'Total signal intensity';
            app.NbNwField = uieditfield(app.GridLayout12, 'numeric');
            app.NbNwField.Editable = 'off';
            app.AvgIntensityField = uieditfield(app.GridLayout12, 'numeric');
            app.AvgIntensityField.LowerLimitInclusive = 'off';
            app.AvgIntensityField.UpperLimitInclusive = 'off';
            app.AvgIntensityField.Editable = 'off';
            app.SumIntensityField = uieditfield(app.GridLayout12, 'numeric');
            app.SumIntensityField.LowerLimitInclusive = 'off';
            app.SumIntensityField.UpperLimitInclusive = 'off';
            app.SumIntensityField.Editable = 'off';
            app.BackgroundintensityLabel = uilabel(app.GridLayout12);
            app.BackgroundintensityLabel.Text = 'Background intensity';
            app.BackgroundIntensity = uieditfield(app.GridLayout12, 'numeric');
            app.BackgroundIntensity.LowerLimitInclusive = 'off';
            app.BackgroundIntensity.UpperLimitInclusive = 'off';
            app.BackgroundIntensity.Editable = 'off';
            app.TotalROIintensity = uieditfield(app.GridLayout12, 'numeric');
            app.TotalROIintensity.Editable = 'off';
            app.TotalROIintensity.Visible = 'off';
            app.AverageROIintensityLabel = uilabel(app.GridLayout12);
            app.AverageROIintensityLabel.Visible = 'off';
            app.AverageROIintensityLabel.Text = 'Average ROI intensity';
            app.AnalysisPanel = uipanel(app.GridLayout5);
            app.AnalysisPanel.Title = 'Analysis';
            app.GridLayout7 = uigridlayout(app.AnalysisPanel);
            app.WindowSizeLabel = uilabel(app.GridLayout7);
            app.WindowSizeLabel.Text = {'Window'; 'Size'};
            app.GradientWindow = uieditfield(app.GridLayout7, 'numeric');
            app.GradientWindow.Value = 3;
            app.GradientThresholdEditFieldLabel = uilabel(app.GridLayout7);
            app.GradientThresholdEditFieldLabel.Text = 'Gradient Threshold';
            app.GradientThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.GradientThreshold.Value = 100;
            app.LocalizeandFitButton = uibutton(app.GridLayout7, 'push');
            app.LocalizeandFitButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizeandFitButtonPushed, true);
            app.LocalizeandFitButton.Text = 'Localize and Fit';
            app.ImagePanel = uipanel(app.GridLayout5);
            app.ImagePanel.Title = 'Image';
            app.GridLayout7_2 = uigridlayout(app.ImagePanel);
            app.UseTheEnhancedImage = uicheckbox(app.GridLayout7_2);
            app.UseTheEnhancedImage.ValueChangedFcn = createCallbackFcn(app, @UseTheEnhancedImageValueChanged, true);
            app.UseTheEnhancedImage.Enable = 'off';
            app.UseTheEnhancedImage.Text = 'Use the enhanced image';
            app.UseTimeAveragedImage = uicheckbox(app.GridLayout7_2);
            app.UseTimeAveragedImage.ValueChangedFcn = createCallbackFcn(app, @UseTimeAveragedImageValueChanged, true);
            app.UseTimeAveragedImage.Text = 'Use the time averaged image';
            app.ImporttheROI = uibutton(app.GridLayout7_2, 'state');
            app.ImporttheROI.ValueChangedFcn = createCallbackFcn(app, @ImporttheROIValueChanged, true);
            app.ImporttheROI.Enable = 'off';
            app.ImporttheROI.Text = 'Use the ROI';
            app.ContrastPanel = uipanel(app.GridLayout5);
            app.ContrastPanel.Title = 'Contrast';
            app.GridLayout16 = uigridlayout(app.ContrastPanel);
            app.SliderContrast = uislider(app.GridLayout16);
            app.SliderContrast.Limits = [0.5 50];
            app.SliderContrast.MajorTicks = [];
            app.SliderContrast.ValueChangedFcn = createCallbackFcn(app, @SliderContrastValueChanged, true);
            app.SliderContrast.MinorTicks = [];
            app.SliderContrast.Value = 50;
            app.MultidomainImageVisualizationTab = uitab(app.TabGroup);
            app.MultidomainImageVisualizationTab.Title = 'Multidomain Image Visualization';
            app.MultidomainImageVisualizationTab.ButtonDownFcn = createCallbackFcn(app, @MultidomainImageVisualizationTabButtonDown, true);
            app.GridLayout13 = uigridlayout(app.MultidomainImageVisualizationTab);
            app.BrAxes = uiaxes(app.GridLayout13);
            title(app.BrAxes, 'Brightfield')
            app.BrAxes.XTick = [];
            app.BrAxes.YTick = [];
            app.FlAxes = uiaxes(app.GridLayout13);
            title(app.FlAxes, 'Original')
            app.FlAxes.XTick = [];
            app.FlAxes.YTick = [];
            app.Panel_4 = uipanel(app.GridLayout13);
            app.GridLayout15 = uigridlayout(app.Panel_4);
            app.FourierDomainEnhancementButton = uibutton(app.GridLayout15, 'push');
            app.FourierDomainEnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @FourierDomainEnhancementButtonPushed, true);
            app.FourierDomainEnhancementButton.Text = 'Fourier Domain Enhancement';
            app.ObtaintheROIButton = uibutton(app.GridLayout15, 'push');
            app.ObtaintheROIButton.ButtonPushedFcn = createCallbackFcn(app, @ObtaintheROIButtonPushed, true);
            app.ObtaintheROIButton.Text = 'Obtain the ROI';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ChangeParametersMenu = uimenu(app.ContextMenu);
            app.ChangeParametersMenu.Text = 'Change Parameters';
            app.AnalysisMenu = uimenu(app.ChangeParametersMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.ImagingMenu = uimenu(app.ChangeParametersMenu);
            app.ImagingMenu.Text = 'Imaging';
            % Assign app.ContextMenu
            app.LocalizeandFitButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu2);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.FlAxes1.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nanoLoci

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end