classdef voronoiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        DisplayVoronoiPlotButton   matlab.ui.control.Button
        RemoveOutliersButton       matlab.ui.control.Button
        ParametersPanel            matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        InvertBrightfieldCheckBox  matlab.ui.control.CheckBox
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        xy          % locations in x and y of spots
        grid        % pattern obtained from brightfield or general Fourier
        im          % current image on which Voronoi is processed
        indexVor    % hot encoded vector of the outliers (-1 = outlier)
        fr          % frame on which voronoi is performed
        batch       % batch on which voronoi is performed
    end
    
    properties (Access = public)
        mainApp     % Main App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, voronoi, gridF, im, fr, batch)
            app.mainApp = mainapp;
            app.xy = voronoi(:,1:2);
            app.grid = gridF;
            app.im = im;
            app.fr = fr;
            app.batch = batch;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {385, 385};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {196, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DisplayVoronoiPlotButton
        function DisplayVoronoiPlotButtonPushed(app, event)
            [locFilt, locN, app.indexVor] = ...
                voronoi_tessalation(app.ThresholdEditField.Value,app.xy,app.grid,app.InvertBrightfieldCheckBox.Value);
            colormap(app.UIAxes,'gray')
                                                                                                                                                                                                           imagesc(app.UIAxes,app.im)
            axis(app.UIAxes,[1 size(app.im,1) 1 size(app.im,2)])
            hold(app.UIAxes,'on');
            voronoi(app.UIAxes, locN(:,1),locN(:,2))
            plot(app.UIAxes, app.xy(:,1),app.xy(:,2),'ro');
            plot(app.UIAxes, locFilt(:,1),locFilt(:,2),'gx');
            hold(app.UIAxes,'off');

            disp(app.indexVor)
        end

        % Button pushed function: RemoveOutliersButton
        function RemoveOutliersButtonPushed(app, event)
            Dialogs.removeOutliersVoronoi(app.indexVor,app.fr,app.batch,app);
            disp(app.mainApp.det.Fit{app.fr,app.batch})
            update_plot(app.mainApp)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout3 = uigridlayout(app.ParametersPanel);
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout3);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ThresholdEditField.Value = 1.2;
            app.InvertBrightfieldCheckBox = uicheckbox(app.GridLayout3);
            app.InvertBrightfieldCheckBox.Text = 'Invert Brightfield';
            app.RemoveOutliersButton = uibutton(app.GridLayout2, 'push');
            app.RemoveOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersButtonPushed, true);
            app.RemoveOutliersButton.Text = 'Remove Outliers';
            app.DisplayVoronoiPlotButton = uibutton(app.GridLayout2, 'push');
            app.DisplayVoronoiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayVoronoiPlotButtonPushed, true);
            app.DisplayVoronoiPlotButton.Text = 'Display Voronoi Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Voronoi Plot ')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voronoiApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end